classdef WGS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SLMWGSTweezerUIFigure          matlab.ui.Figure
        Phase_fixed                    matlab.ui.control.NumericEditField
        PhasefixedpointLabel           matlab.ui.control.Label
        Explanation2Generate_2         matlab.ui.control.Label
        Explanation2Convert            matlab.ui.control.Label
        CurrWL                         matlab.ui.control.NumericEditField
        WavelengthyouwantLabel         matlab.ui.control.Label
        RatedWL                        matlab.ui.control.NumericEditField
        RatedWavelengthLabel           matlab.ui.control.Label
        ConvertWavelengthButton        matlab.ui.control.Button
        Explanation2Generate           matlab.ui.control.Label
        Retrived_Pic                   matlab.ui.container.Panel
        Resolution_Y                   matlab.ui.control.NumericEditField
        Resolution_YLabel              matlab.ui.control.Label
        Resolution_X                   matlab.ui.control.NumericEditField
        Resolution_XLabel              matlab.ui.control.Label
        GenerateHologramButton         matlab.ui.control.Button
        IterationNumber                matlab.ui.control.NumericEditField
        IterationNumberEditFieldLabel  matlab.ui.control.Label
        ColumnSpacing                  matlab.ui.control.NumericEditField
        ColumnSpacingEditFieldLabel    matlab.ui.control.Label
        RowSpacing                     matlab.ui.control.NumericEditField
        RowSpacingEditFieldLabel       matlab.ui.control.Label
        Column                         matlab.ui.control.NumericEditField
        ColumnEditFieldLabel           matlab.ui.control.Label
        Row                            matlab.ui.control.NumericEditField
        RowEditFieldLabel              matlab.ui.control.Label
        Error                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateHologramButton
        function GenerateHologramButtonPushed(app, event)
            row = app.Row.Value;
            column = app.Column.Value;
            row_spacing = app.RowSpacing.Value;
            column_spacing = app.ColumnSpacing.Value;
            iteration_num = app.IterationNumber.Value;
            Resolution = [app.Resolution_X.Value app.Resolution_Y.Value];
            %% Generate the SLM plane intensity: our resolution is Resolution(1)*Resolution(2)
	            x = linspace(-10,10,Resolution(1));
	            y = linspace(-10,10,Resolution(2));
	            [X,Y] = meshgrid(x,y);
	            x0 = 0;     		% center
	            y0 = 0;     		% center
	            sigma = 5; 			% effective beam waist
                sigma_x = sigma/1.92;
                sigma_y = sigma/1.2;
	            A = 1;      		% peak of the beam 
	            res = ((X-x0).^2./(2*sigma_x^2) + (Y-y0).^2./(2*sigma_y^2));
	            input_intensity = A  * exp(-res);
            
            %% Generate the target picture
                Blank_pic = zeros(Resolution(2),Resolution(1));
            
            for i = 1:column
                for j = 1:row
                    Blank_pic(600+column_spacing*i,960-row_spacing*j) = 1;
                end
            end
            
                weight = ones(column,row);
            
            %% Prepare the target picture
	            Target=double(Blank_pic);
	            A = fftshift(ifft2(fftshift(Target)));
                non_uniformity = [];
                
            %% Start Weighted Gershberg-Saxton algorithm iteration
            for k=1:iteration_num
                B = abs(input_intensity) .* exp(1i*angle(A));
                B = B / max(max(abs(B)));
                C = fftshift(fft2(fftshift(B)));
                if k < app.Phase_fixed.Value
                    phase_fixed = exp(1i*angle(C));
                end
                
                intensity_retrival = abs(C);
                aver_intensity_retrival = 0;
            
                for i = 1:column % calculate retrival intensity, next we can weighted
                    for j = 1:row
                        aver_intensity_retrival = intensity_retrival(600+column_spacing*i,960-row_spacing*j) + aver_intensity_retrival;
                    end
                end
                aver_intensity_retrival = aver_intensity_retrival / row / column;
                
                for i = 1:column % weighted part
                    for j = 1:row
                        weight(i,j) = weight(i,j) / (intensity_retrival(600+column_spacing*i,960-row_spacing*j)/aver_intensity_retrival);
                    end
                end
            
                D = abs(Target) .* phase_fixed;
                for i = 1:column % weighted part
                    for j = 1:row
                        D(600+column_spacing*i,960-row_spacing*j) = D(600+column_spacing*i,960-row_spacing*j) * weight(i,j);
                    end
                end
                D = D/max(max(abs(D)));
                A = fftshift(ifft2(fftshift(D)));

                Retrive = abs(C)/max(max(abs(C)));
                intensity_distribution = zeros(column,row);
                for i = 1:column % weighted part
                    for j = 1:row
                        intensity_distribution(i,j) = Retrive(600+column_spacing*i,960-row_spacing*j);
                    end
                end
%                 error = [error; sum(sum(abs(intensity_retrival/sum(sum(intensity_retrival)) - abs(Blank_pic))))];
                non_uniformity = [non_uniformity; std(std(intensity_distribution))/mean(mean(intensity_distribution))];
            end

            %% Plot results
                % Error
	            plot(app.Error,non_uniformity);
                % Hologram
                SLM_phase = im2uint8((angle(A)+pi)/2/pi);
	            imwrite(SLM_phase,'phase.png');
                % Intensity Distribution
                Inten_Distribute = abs(C);
                Inten_Distribute = Inten_Distribute(600:600+column_spacing*(column+1),960-row_spacing*(row+1):960);
                heatmap(app.Retrived_Pic,Inten_Distribute,'Colormap',colormap('hot'));%,'XDisplayLabels',nan(1920,1),'YDisplayLabels',nan(1200,1));
%                 Ax = gca;
%                 Ax.XDisplayLabels = nan(size(Ax.XDisplayData));
%                 Ax.YDisplayLabels = nan(size(Ax.YDisplayData));
        end

        % Button pushed function: ConvertWavelengthButton
        function ConvertWavelengthButtonPushed(app, event)
            ratio = double(app.CurrWL.Value/app.RatedWL.Value);

            before = imread("phase.png"); before = double(before);
            after = before * ratio / 255; after = uint8(after); after = im2uint8(after);
            
            imwrite(after,"phase_converted.png");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SLMWGSTweezerUIFigure = uifigure('Visible', 'off');
            app.SLMWGSTweezerUIFigure.Name = 'SLM WGS Tweezer';
            app.SLMWGSTweezerUIFigure.Icon = 'heatmap.png';
            app.Error = uiaxes(app.SLMWGSTweezerUIFigure);
            title(app.Error, 'Non-uniformity')
            xlabel(app.Error, 'Iterations')
            zlabel(app.Error, 'Z')
            app.Error.YScale = 'log';
            app.RowEditFieldLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.Row = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.Row.Value = 3;
            app.ColumnEditFieldLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.ColumnEditFieldLabel.Text = 'Column';
            app.Column = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.Column.Value = 3;
            app.RowSpacingEditFieldLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.RowSpacingEditFieldLabel.Text = 'Row Spacing';
            app.RowSpacing = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.RowSpacing.Value = 15;
            app.ColumnSpacingEditFieldLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.ColumnSpacingEditFieldLabel.Text = 'Column Spacing';
            app.ColumnSpacing = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.ColumnSpacing.Value = 10;
            app.IterationNumberEditFieldLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumber = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.IterationNumber.Value = 50;
            app.GenerateHologramButton = uibutton(app.SLMWGSTweezerUIFigure, 'push');
            app.GenerateHologramButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateHologramButtonPushed, true);
            app.GenerateHologramButton.Text = 'Generate Hologram';
            app.Resolution_XLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.Resolution_XLabel.Text = 'Resolution_X';
            app.Resolution_X = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.Resolution_X.Value = 1920;
            app.Resolution_YLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.Resolution_YLabel.Text = 'Resolution_Y';
            app.Resolution_Y = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.Resolution_Y.Value = 1200;
            app.Retrived_Pic = uipanel(app.SLMWGSTweezerUIFigure);
            app.Retrived_Pic.Title = 'What the pattern supposed to be?';
            app.Explanation2Generate = uilabel(app.SLMWGSTweezerUIFigure);
            app.Explanation2Generate.Text = 'Hologram saved in current directory "phase.png"';
            app.ConvertWavelengthButton = uibutton(app.SLMWGSTweezerUIFigure, 'push');
            app.ConvertWavelengthButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertWavelengthButtonPushed, true);
            app.ConvertWavelengthButton.Text = 'Convert Wavelength';
            app.RatedWavelengthLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.RatedWavelengthLabel.Text = 'Rated Wavelength (nm)';
            app.RatedWL = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.RatedWL.Value = 800;
            app.WavelengthyouwantLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.WavelengthyouwantLabel.Text = 'Wavelength you want (nm)';
            app.CurrWL = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.CurrWL.Value = 780;
            app.Explanation2Convert = uilabel(app.SLMWGSTweezerUIFigure);
            app.Explanation2Convert.Text = 'Hologram converted to "phase_converted.png"';
            app.Explanation2Generate_2 = uilabel(app.SLMWGSTweezerUIFigure);
            app.Explanation2Generate_2.Text = {'Tweezer Generation with'; 'Weighted Gershberg-Saxton Algorithm'};
            app.PhasefixedpointLabel = uilabel(app.SLMWGSTweezerUIFigure);
            app.PhasefixedpointLabel.Text = 'Phase fixed point';
            app.Phase_fixed = uieditfield(app.SLMWGSTweezerUIFigure, 'numeric');
            app.Phase_fixed.Value = 40;
            % Show the figure after all components are created
            app.SLMWGSTweezerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WGS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SLMWGSTweezerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SLMWGSTweezerUIFigure)
        end
    end
end